classdef vehicle_model < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RFRVehicleModelUIFigure         matlab.ui.Figure
        MainMenuPanel                   matlab.ui.container.Panel
        SidebarPanel                    matlab.ui.container.Panel
        SimulatePanel                   matlab.ui.container.Panel
        SimulateButton                  matlab.ui.control.Button
        ChangeCarPropertiesButton       matlab.ui.control.Button
        TrackPanel                      matlab.ui.container.Panel
        TrackChangeButton               matlab.ui.control.Button
        TrackNameLabel                  matlab.ui.control.Label
        TrackLabel                      matlab.ui.control.Label
        VehicleLabel                    matlab.ui.control.Label
        VehicleSelectionPanel           matlab.ui.container.Panel
        ChangeVehicleButton             matlab.ui.control.Button
        VehicleNameLabel                matlab.ui.control.Label
        AnalysisPanel                   matlab.ui.container.Panel
        TitlePanel                      matlab.ui.container.Panel
        Image                           matlab.ui.control.Image
        RyersonFormulaRacingLabel       matlab.ui.control.Label
        VehicleModelLabel               matlab.ui.control.Label
        SimulationPanel                 matlab.ui.container.Panel
        RightSidePanel                  matlab.ui.container.Panel
        ManageSimulationPanel           matlab.ui.container.Panel
        StopButton                      matlab.ui.control.Button
        PauseButton                     matlab.ui.control.Button
        RHSLeftPanel                    matlab.ui.container.Panel
        GearLabel                       matlab.ui.control.Label
        GearTextLabel                   matlab.ui.control.Label
        ThrottleBrakePanel              matlab.ui.container.Panel
        BrakeGaugeLabel                 matlab.ui.control.Label
        BrakeGauge                      matlab.ui.control.LinearGauge
        ThrottleGauge_2Label            matlab.ui.control.Label
        ThrottleGauge                   matlab.ui.control.LinearGauge
        RHSRPanel                       matlab.ui.container.Panel
        SpeedGaugeLabel                 matlab.ui.control.Label
        SpeedGauge                      matlab.ui.control.Gauge
        RPMGaugeLabel                   matlab.ui.control.Label
        RPMGauge                        matlab.ui.control.Gauge
        x1000Label                      matlab.ui.control.Label
        kmhLabel                        matlab.ui.control.Label
        TimeLabel                       matlab.ui.control.Label
        CarPropertiesPanel              matlab.ui.container.Panel
        PropertiesPanel                 matlab.ui.container.Panel
        SuspensionPanel                 matlab.ui.container.Panel
        ChangeSuspensionGeometryButton  matlab.ui.control.Button
        SpringRatesPanel                matlab.ui.container.Panel
        FrontLabel                      matlab.ui.control.Label
        FrontSpringRateEditField        matlab.ui.control.NumericEditField
        RearLabel                       matlab.ui.control.Label
        RearSpringRateEditField         matlab.ui.control.NumericEditField
        FrontSpringRateUnitsDropDown    matlab.ui.control.DropDown
        RearSpringRateUnitsDropDown     matlab.ui.control.DropDown
        DamperRatesPanel                matlab.ui.container.Panel
        FrontLabel_2                    matlab.ui.control.Label
        FrontDamperRatesEditField       matlab.ui.control.NumericEditField
        RearLabel_2                     matlab.ui.control.Label
        RearDamperRatesEditField        matlab.ui.control.NumericEditField
        FrontDamperRatesUnitsDropDown   matlab.ui.control.DropDown
        RearDamperRatesUnitsDropdown    matlab.ui.control.DropDown
        SteeringPanel                   matlab.ui.container.Panel
        ChangeSteeringGeometryButton    matlab.ui.control.Button
        ToeAnglesPanel                  matlab.ui.container.Panel
        FrontLabel_3                    matlab.ui.control.Label
        FrontToeEditField               matlab.ui.control.NumericEditField
        RearLabel_3                     matlab.ui.control.Label
        RearToeEditField                matlab.ui.control.NumericEditField
        FrontToeUnitsDrownDown          matlab.ui.control.DropDown
        RearToeUnitsDropDown            matlab.ui.control.DropDown
        CasterUnitsDropDown             matlab.ui.control.DropDown
        CasteranglesLabel               matlab.ui.control.Label
        CasterEditField                 matlab.ui.control.NumericEditField
        GeneralPanel                    matlab.ui.container.Panel
        CarMassUnitsDropDown            matlab.ui.control.DropDown
        TiresLabel                      matlab.ui.control.Label
        TiresDropDown                   matlab.ui.control.DropDown
        CGPositionPanel                 matlab.ui.container.Panel
        HeightLabel                     matlab.ui.control.Label
        CGHeightEditField               matlab.ui.control.NumericEditField
        CGHeightUnitsDropDown           matlab.ui.control.DropDown
        CGLengthUnitsDropDown           matlab.ui.control.DropDown
        LengthEditFieldLabel            matlab.ui.control.Label
        CGLengthEditField               matlab.ui.control.NumericEditField
        WheelbaseLabel                  matlab.ui.control.Label
        WheelbaseEditField              matlab.ui.control.NumericEditField
        WheelbaseUnitsDropDown          matlab.ui.control.DropDown
        YawinertiaLabel                 matlab.ui.control.Label
        YawInertiaEditField             matlab.ui.control.NumericEditField
        YawInertiaUnitsDropDown         matlab.ui.control.DropDown
        TrackWidthPanel                 matlab.ui.container.Panel
        FrontTrackWidthUnitsDropDown    matlab.ui.control.DropDown
        FrontEditField_5Label           matlab.ui.control.Label
        FrontTrackWidthEditField        matlab.ui.control.NumericEditField
        RearEditField_5Label            matlab.ui.control.Label
        RearTrackWidthEditField         matlab.ui.control.NumericEditField
        RearTrackWidthUnitsDropDown     matlab.ui.control.DropDown
        CarmassEditFieldLabel           matlab.ui.control.Label
        CarMassEditField                matlab.ui.control.NumericEditField
        AerodynamicsPanel               matlab.ui.container.Panel
        AeromassLabel                   matlab.ui.control.Label
        AeroMassEditField               matlab.ui.control.NumericEditField
        AeroMassUnitsDropDown           matlab.ui.control.DropDown
        DragcoefficientEditFieldLabel   matlab.ui.control.Label
        DragCoefficientEditField        matlab.ui.control.NumericEditField
        LiftcoefficientEditFieldLabel   matlab.ui.control.Label
        LiftCoefficientEditField        matlab.ui.control.NumericEditField
        FrontalareaLabel                matlab.ui.control.Label
        FrontalAreaEditField            matlab.ui.control.NumericEditField
        FrontalAreaUnitsDropDown        matlab.ui.control.DropDown
        SaveButton                      matlab.ui.control.Button
        CancelButton                    matlab.ui.control.Button
    end

    
    properties (Access = private)
        State       SimulationState     % Simulation state (running, paused, stopped)
        Model       double              % Model reference
        DrivetrainModel double          % Drivetrain model reference
        SpeedListenerHandle
        RPMListenerHandle
        ThrottleListenerHandle
        BrakeListenerHandle
        GearListenerHandle
        OldModelProperties struct = struct( ...
            'StopTime', 0, ...
            'StartFcn', '', ...
            'StopFcn', '' ...
            )
    end
    
    methods (Access = private)
        
    end
    
    methods (Access = public)
        
        function changeSimulationState(app, newState)
            oldState = app.State;
            app.State = newState;
            
            switch newState
                
                case SimulationState.Running % Run the sim
                    
                    app.PauseButton.Text = 'Pause';
                    
                    if oldState == SimulationState.Stopped
                        set_param(app.Model,'SimulationCommand','start'); % Start the model
                    else
                        set_param(app.Model,'SimulationCommand','continue'); % Resume the model
                    end
                    
                case SimulationState.Paused % Pause the sim
                    
                    app.PauseButton.Text = 'Resume';
                    
                    set_param(app.Model,'SimulationCommand','pause');
                    
                case SimulationState.Stopped % Stop the sim
                    
                    % Disable the buttons
                    app.PauseButton.Enable = false;
                    app.StopButton.Enable = false;
                    
                    % Stop the simulation
                    set_param(app.Model,'SimulationCommand','stop');
                    
                    % Reset to the old properties
                    set_param(app.Model, 'StopTime', app.OldModelProperties.StopTime);
                    set_param(app.Model, 'StartFcn', app.OldModelProperties.StartFcn);
                    set_param(app.Model, 'StopFcn', app.OldModelProperties.StopFcn);
                    
                    % Reset the gauges
                    app.SpeedGauge.Value = 0;
                    app.RPMGauge.Value = 0;
                    app.ThrottleGauge.Value = 0;
                    app.BrakeGauge.Value = 0;
            end
        end
        
        function changeAppState(app, newState)
            switch newState
                case AppState.CarProperties
                    app.CarPropertiesPanel.Visible = true;
                    app.SimulationPanel.Visible = false;
                    app.MainMenuPanel.Visible = false;
                    
                    dict = Simulink.data.dictionary.open('Properties.sldd');
                    dataSection = getSection(dict, 'Design Data');
                    
                    % General
                    carMassParam = getValue(getEntry(dataSection, 'Car_mass'));
                    app.CarMassEditField.Value = carMassParam.Value;
                    app.CarMassUnitsDropDown.Value = carMassParam.Unit;
                    
                    app.TiresDropDown.Enable = false;
                    
                    cgHeightParam = getValue(getEntry(dataSection, 'CG_z'));
                    app.CGHeightEditField.Value = cgHeightParam.Value;
                    app.CGHeightEditField.Enable = false;
                    app.CGHeightUnitsDropDown.Value = cgHeightParam.Unit;
                    
                    cgLengthParam = getValue(getEntry(dataSection, 'CG_x'));
                    app.CGLengthEditField.Value = cgLengthParam.Value;
                    app.CGLengthUnitsDropDown.Value = cgLengthParam.Unit;
                    
                    wheelbaseParam = getValue(getEntry(dataSection, 'Wheelbase'));
                    app.WheelbaseEditField.Value = wheelbaseParam.Value;
                    app.WheelbaseUnitsDropDown.Value = wheelbaseParam.Unit;
                    
                    yawInertiaParam = getValue(getEntry(dataSection, 'Car_Iz'));
                    app.YawInertiaEditField.Value = yawInertiaParam.Value;
                    app.YawInertiaUnitsDropDown.Value = yawInertiaParam.Unit;
                    
                    trackWidthParam = getValue(getEntry(dataSection, 'Track_width'));
                    trackWidth = trackWidthParam.Value;
                    app.FrontTrackWidthEditField.Value = trackWidth(1);
                    app.FrontTrackWidthUnitsDropDown.Value = trackWidthParam.Unit;
                    
                    app.RearTrackWidthEditField.Value = trackWidth(1); % Change to (2) once thats changed
                    app.RearTrackWidthEditField.Enable = false;
                    app.RearTrackWidthUnitsDropDown.Value = trackWidthParam.Unit;
                    
                    % Suspension
                    app.ChangeSuspensionGeometryButton.Enable = false;
                    
                    springRateParam = getValue(getEntry(dataSection, 'Shock_stiffness'));
                    springRate = springRateParam.Value;
                    app.FrontSpringRateEditField.Value = springRate(1);
                    app.FrontSpringRateEditField.Enable = false;
                    app.FrontSpringRateUnitsDropDown.Value = springRateParam.Unit;
                    
                    app.RearSpringRateEditField.Value = springRate(2);
                    app.RearSpringRateEditField.Enable = false;
                    app.RearSpringRateUnitsDropDown.Value = springRateParam.Unit;
                    
                    damperRateParam = getValue(getEntry(dataSection, 'Shock_damping'));
                    damperRate = damperRateParam.Value;
                    app.FrontDamperRatesEditField.Value = damperRate(1);
                    app.FrontDamperRatesEditField.Enable = false;
                    app.FrontDamperRatesUnitsDropDown.Value = damperRateParam.Unit;
                    
                    app.RearDamperRatesEditField.Value = damperRate(2);
                    app.RearDamperRatesEditField.Enable = false;
                    app.RearDamperRatesUnitsDropdown.Value = damperRateParam.Unit;
                    
                    % Steering
                    app.ChangeSteeringGeometryButton.Enable = false;
                    app.FrontToeEditField.Enable = false;
                    app.FrontToeUnitsDrownDown.Enable = false;
                    app.RearToeEditField.Enable = false;
                    app.RearToeUnitsDropDown.Enable = false;
                    app.CasterEditField.Enable = false;
                    app.CasterUnitsDropDown.Enable = false;
                    
                    % Aero
                    app.AeroMassEditField.Enable = false;
                    app.AeroMassUnitsDropDown.Enable = false;
                    app.DragCoefficientEditField.Enable = false;
                    app.LiftCoefficientEditField.Enable = false;
                    app.FrontalAreaEditField.Enable = false;
                    app.FrontalAreaUnitsDropDown.Enable = false;
                    
                case AppState.Simulation
                    app.SimulationPanel.Visible = true;
                    app.MainMenuPanel.Visible = false;
                    app.CarPropertiesPanel.Visible = false;
                    
                case AppState.Start
                    app.SimulationPanel.Visible = false;
                    app.MainMenuPanel.Visible = true;
                    app.CarPropertiesPanel.Visible = false;
            end
        end
        
        function registerListeners(app)
            app.SpeedListenerHandle = add_exec_event_listener( ...
                'Vehicle_Model/Car velocities/Integrator1', ...
                'PostOutputs', ...
                @(block, event) updateSpeed(app, block));
            app.RPMListenerHandle = add_exec_event_listener( ...
                'Drivetrain_2Track/Gain1', ...
                'PostOutputs', ...
                @(block, event) updateRPM(app, block));
            app.ThrottleListenerHandle = add_exec_event_listener( ...
                'Vehicle_Model/Throttle Input', ...
                'PostOutputs', ...
                @(block, event) updateThrottle(app, block));
            app.BrakeListenerHandle = add_exec_event_listener( ...
                'Vehicle_Model/Brake pedal', ...
                'PostOutputs', ...
                @(block, event) updateBrake(app, block));
            app.GearListenerHandle = add_exec_event_listener( ...
                'Vehicle_Model/Gear Input', ...
                'PostOutputs', ...
                @(block, event) updateGear(app, block));
        end
        
        function stopSimulation(app)
            app.changeSimulationState(SimulationState.Stopped);
        end
        
        function updateSpeed(app, block)
            value = block.OutputPort(1).Data;
            app.SpeedGauge.Value = value(1) * 3.6;
        end
        
        function updateRPM(app, block)
            value = block.OutputPort(1).Data;
            app.RPMGauge.Value = value(1) / 1000;
        end
        
        function updateThrottle(app, block)
            value = block.OutputPort(1).Data;
            app.ThrottleGauge.Value = value(1) * 100;
        end
        
        function updateBrake(app, block)
            value = block.OutputPort(1).Data;
            app.BrakeGauge.Value = value(1) * 100;
        end
        
        function updateGear(app, block)
            value = block.OutputPort(1).Data;
            app.GearLabel.Text = num2str(value);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.MainMenuPanel.Position = app.RFRVehicleModelUIFigure.Position;
            app.SimulationPanel.Position = app.RFRVehicleModelUIFigure.Position;
            
            changeAppState(app, AppState.Start);
            
            global app_global;
            app_global = app;
        end

        % Button pushed function: PauseButton
        function pauseButtonPushed(app, event)
            switch app.State
                case SimulationState.Running
                    changeSimulationState(app, SimulationState.Paused);
                case SimulationState.Paused
                    changeSimulationState(app, SimulationState.Running);
            end
        end

        % Button pushed function: SimulateButton
        function simulateButtonPushed(app, event)
            app.MainMenuPanel.Visible = false;
            app.SimulationPanel.Visible = true;
            
            app.Model = load_system('Vehicle_Model.slx'); % Load the model
            app.DrivetrainModel = load_system('Drivetrain_2Track.slx'); % Load the drivetrain
            
            % Save the old properties
            app.OldModelProperties.StopTime = get_param(app.Model,'StopTime');
            app.OldModelProperties.StartFcn = get_param(app.Model,'StartFcn');
            app.OldModelProperties.StartFcn = get_param(app.Model,'StopFcn');
            
            set_param(app.Model,'StopTime','inf');
            set_param(app.Model,'StartFcn','global app_global; app_global.registerListeners');
            set_param(app.Model,'StopFcn','global app_global; app_global.stopSimulation');
            
            get_param(app.Model,'SimulationStatus')
            
            changeSimulationState(app, SimulationState.Running);
            
        end

        % Size changed function: RFRVehicleModelUIFigure
        function RFRVehicleModelUIFigureSizeChanged(app, event)
            
            position = app.RFRVehicleModelUIFigure.Position;
            app.MainMenuPanel.Position = position;
            app.MainMenuPanel.Position(2) = position(2) - 41;
            %app.MainMenuPanel.Position(4) = position(4) + 41;
            
            app.SimulationPanel.Position = position;
            app.SimulationPanel.Position(2) = position(2) - 41;
            app.SimulationPanel.Position(4) = position(4) + 41;
            
            app.SidebarPanel.Position(1) = app.MainMenuPanel.Position(3) - app.SidebarPanel.Position(3);
            app.SidebarPanel.Position(2) = app.MainMenuPanel.Position(2);
            app.SidebarPanel.Position(4) = app.MainMenuPanel.Position(4) + 1;
            
            app.TitlePanel.Position(2) = app.MainMenuPanel.Position(4) - app.TitlePanel.Position(4);
            app.TitlePanel.Position(3) = app.MainMenuPanel.Position(3) - app.SidebarPanel.Position(3);
            
            app.RightSidePanel.Position(1) = app.SimulationPanel.Position(3) - app.RightSidePanel.Position(3);
            app.RightSidePanel.Position(2) = app.SimulationPanel.Position(2);
            app.RightSidePanel.Position(4) = app.SimulationPanel.Position(4);
            
            app.TimeLabel.Position(1) = app.RightSidePanel.Position(1) - 100;
            app.TimeLabel.Position(2) = app.SimulationPanel.Position(4) - 80;
            
            app.CarPropertiesPanel.Position = position;
            app.CarPropertiesPanel.Position(2) = position(2) - 41;
            app.PropertiesPanel.Position(1) = position(3) - app.PropertiesPanel.Position(3);
            app.PropertiesPanel.Position(2) = app.CarPropertiesPanel.Position(2);
            app.PropertiesPanel.Position(4) = app.CarPropertiesPanel.Position(4) + 1;
        end

        % Close request function: RFRVehicleModelUIFigure
        function RFRVehicleModelUIFigureCloseRequest(app, event)
            delete(app)
            %close_system(app.Model, false);
        end

        % Button pushed function: ChangeCarPropertiesButton
        function ChangeCarPropertiesButtonPushedFcn(app, event)
            changeAppState(app, AppState.CarProperties);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RFRVehicleModelUIFigure = uifigure('Visible', 'off');
            app.RFRVehicleModelUIFigure.Name = 'RFR Vehicle Model';
            app.RFRVehicleModelUIFigure.CloseRequestFcn = createCallbackFcn(app, @RFRVehicleModelUIFigureCloseRequest, true);
            app.RFRVehicleModelUIFigure.SizeChangedFcn = createCallbackFcn(app, @RFRVehicleModelUIFigureSizeChanged, true);
            app.MainMenuPanel = uipanel(app.RFRVehicleModelUIFigure);
            app.SidebarPanel = uipanel(app.MainMenuPanel);
            app.SimulatePanel = uipanel(app.SidebarPanel);
            app.SimulatePanel.Title = 'Simulate';
            app.SimulateButton = uibutton(app.SimulatePanel, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @simulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.ChangeCarPropertiesButton = uibutton(app.SimulatePanel, 'push');
            app.ChangeCarPropertiesButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeCarPropertiesButtonPushedFcn, true);
            app.ChangeCarPropertiesButton.Text = 'Change Car Properties';
            app.TrackPanel = uipanel(app.SimulatePanel);
            app.TrackChangeButton = uibutton(app.TrackPanel, 'push');
            app.TrackChangeButton.Text = 'Change';
            app.TrackNameLabel = uilabel(app.TrackPanel);
            app.TrackNameLabel.Enable = 'off';
            app.TrackNameLabel.Text = 'None selected';
            app.TrackLabel = uilabel(app.SimulatePanel);
            app.TrackLabel.Text = 'Track';
            app.VehicleLabel = uilabel(app.SimulatePanel);
            app.VehicleLabel.Text = 'Vehicle';
            app.VehicleSelectionPanel = uipanel(app.SimulatePanel);
            app.ChangeVehicleButton = uibutton(app.VehicleSelectionPanel, 'push');
            app.ChangeVehicleButton.Text = 'Change';
            app.VehicleNameLabel = uilabel(app.VehicleSelectionPanel);
            app.VehicleNameLabel.Enable = 'off';
            app.VehicleNameLabel.Text = 'None selected';
            app.AnalysisPanel = uipanel(app.SidebarPanel);
            app.AnalysisPanel.Title = 'Analysis';
            app.TitlePanel = uipanel(app.MainMenuPanel);
            app.Image = uiimage(app.TitlePanel);
            app.RyersonFormulaRacingLabel = uilabel(app.TitlePanel);
            app.RyersonFormulaRacingLabel.Text = 'Ryerson Formula Racing';
            app.VehicleModelLabel = uilabel(app.TitlePanel);
            app.VehicleModelLabel.Text = 'Vehicle Model';
            app.SimulationPanel = uipanel(app.RFRVehicleModelUIFigure);
            app.RightSidePanel = uipanel(app.SimulationPanel);
            app.ManageSimulationPanel = uipanel(app.RightSidePanel);
            app.StopButton = uibutton(app.ManageSimulationPanel, 'push');
            app.StopButton.Text = 'Stop';
            app.PauseButton = uibutton(app.ManageSimulationPanel, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @pauseButtonPushed, true);
            app.PauseButton.Text = 'Pause';
            app.RHSLeftPanel = uipanel(app.RightSidePanel);
            app.GearLabel = uilabel(app.RHSLeftPanel);
            app.GearLabel.Text = '1';
            app.GearTextLabel = uilabel(app.RHSLeftPanel);
            app.GearTextLabel.Text = 'Gear';
            app.ThrottleBrakePanel = uipanel(app.RHSLeftPanel);
            app.BrakeGaugeLabel = uilabel(app.ThrottleBrakePanel);
            app.BrakeGaugeLabel.Text = 'Brake';
            app.BrakeGauge = uigauge(app.ThrottleBrakePanel, 'linear');
            app.ThrottleGauge_2Label = uilabel(app.ThrottleBrakePanel);
            app.ThrottleGauge_2Label.Text = 'Throttle';
            app.ThrottleGauge = uigauge(app.ThrottleBrakePanel, 'linear');
            app.RHSRPanel = uipanel(app.RightSidePanel);
            app.SpeedGaugeLabel = uilabel(app.RHSRPanel);
            app.SpeedGaugeLabel.Text = 'Speed';
            app.SpeedGauge = uigauge(app.RHSRPanel, 'circular');
            app.RPMGaugeLabel = uilabel(app.RHSRPanel);
            app.RPMGaugeLabel.Text = 'RPM';
            app.RPMGauge = uigauge(app.RHSRPanel, 'circular');
            app.RPMGauge.Limits = [4 11];
            app.RPMGauge.Value = 4;
            app.x1000Label = uilabel(app.RHSRPanel);
            app.x1000Label.Text = 'x1000';
            app.kmhLabel = uilabel(app.RHSRPanel);
            app.kmhLabel.Text = 'km/h';
            app.TimeLabel = uilabel(app.SimulationPanel);
            app.TimeLabel.Text = '0:00:00';
            app.CarPropertiesPanel = uipanel(app.RFRVehicleModelUIFigure);
            app.PropertiesPanel = uipanel(app.CarPropertiesPanel);
            app.SuspensionPanel = uipanel(app.PropertiesPanel);
            app.SuspensionPanel.Title = 'Suspension';
            app.ChangeSuspensionGeometryButton = uibutton(app.SuspensionPanel, 'push');
            app.ChangeSuspensionGeometryButton.Text = 'Change suspension geometry';
            app.SpringRatesPanel = uipanel(app.SuspensionPanel);
            app.SpringRatesPanel.Title = 'Spring Rates';
            app.FrontLabel = uilabel(app.SpringRatesPanel);
            app.FrontLabel.Text = 'Front:';
            app.FrontSpringRateEditField = uieditfield(app.SpringRatesPanel, 'numeric');
            app.RearLabel = uilabel(app.SpringRatesPanel);
            app.RearLabel.Text = 'Rear:';
            app.RearSpringRateEditField = uieditfield(app.SpringRatesPanel, 'numeric');
            app.FrontSpringRateUnitsDropDown = uidropdown(app.SpringRatesPanel);
            app.FrontSpringRateUnitsDropDown.Items = {'N/m', 'N/mm', '', 'lbf/in'};
            app.FrontSpringRateUnitsDropDown.Value = 'N/m';
            app.RearSpringRateUnitsDropDown = uidropdown(app.SpringRatesPanel);
            app.RearSpringRateUnitsDropDown.Items = {'N/m', 'N/mm', '', 'lbf/in'};
            app.RearSpringRateUnitsDropDown.Value = 'N/m';
            app.DamperRatesPanel = uipanel(app.SuspensionPanel);
            app.DamperRatesPanel.Title = 'Damper Rates';
            app.FrontLabel_2 = uilabel(app.DamperRatesPanel);
            app.FrontLabel_2.Text = 'Front:';
            app.FrontDamperRatesEditField = uieditfield(app.DamperRatesPanel, 'numeric');
            app.RearLabel_2 = uilabel(app.DamperRatesPanel);
            app.RearLabel_2.Text = 'Rear:';
            app.RearDamperRatesEditField = uieditfield(app.DamperRatesPanel, 'numeric');
            app.FrontDamperRatesUnitsDropDown = uidropdown(app.DamperRatesPanel);
            app.FrontDamperRatesUnitsDropDown.Items = {'N*s/m', 'N*s/mm', 'lbf*s/in'};
            app.FrontDamperRatesUnitsDropDown.Value = 'N*s/m';
            app.RearDamperRatesUnitsDropdown = uidropdown(app.DamperRatesPanel);
            app.RearDamperRatesUnitsDropdown.Items = {'N*s/m', 'N*s/mm', 'lbf*s/in'};
            app.RearDamperRatesUnitsDropdown.Value = 'N*s/m';
            app.SteeringPanel = uipanel(app.PropertiesPanel);
            app.SteeringPanel.Title = 'Steering';
            app.ChangeSteeringGeometryButton = uibutton(app.SteeringPanel, 'push');
            app.ChangeSteeringGeometryButton.Text = 'Change steering geometry';
            app.ToeAnglesPanel = uipanel(app.SteeringPanel);
            app.ToeAnglesPanel.Title = 'Toe Angles';
            app.FrontLabel_3 = uilabel(app.ToeAnglesPanel);
            app.FrontLabel_3.Text = 'Front:';
            app.FrontToeEditField = uieditfield(app.ToeAnglesPanel, 'numeric');
            app.RearLabel_3 = uilabel(app.ToeAnglesPanel);
            app.RearLabel_3.Text = 'Rear:';
            app.RearToeEditField = uieditfield(app.ToeAnglesPanel, 'numeric');
            app.FrontToeUnitsDrownDown = uidropdown(app.ToeAnglesPanel);
            app.FrontToeUnitsDrownDown.Items = {'deg', 'rad'};
            app.FrontToeUnitsDrownDown.Value = 'deg';
            app.RearToeUnitsDropDown = uidropdown(app.ToeAnglesPanel);
            app.RearToeUnitsDropDown.Items = {'deg', 'rad'};
            app.RearToeUnitsDropDown.Value = 'deg';
            app.CasterUnitsDropDown = uidropdown(app.SteeringPanel);
            app.CasterUnitsDropDown.Items = {'deg', 'rad'};
            app.CasterUnitsDropDown.Value = 'deg';
            app.CasteranglesLabel = uilabel(app.SteeringPanel);
            app.CasteranglesLabel.Text = 'Caster angles:';
            app.CasterEditField = uieditfield(app.SteeringPanel, 'numeric');
            app.GeneralPanel = uipanel(app.PropertiesPanel);
            app.GeneralPanel.Title = 'General';
            app.CarMassUnitsDropDown = uidropdown(app.GeneralPanel);
            app.CarMassUnitsDropDown.Items = {'kg', 'lbm'};
            app.CarMassUnitsDropDown.Value = 'kg';
            app.TiresLabel = uilabel(app.GeneralPanel);
            app.TiresLabel.Text = 'Tires:';
            app.TiresDropDown = uidropdown(app.GeneralPanel);
            app.CGPositionPanel = uipanel(app.GeneralPanel);
            app.CGPositionPanel.Title = 'CG Position';
            app.HeightLabel = uilabel(app.CGPositionPanel);
            app.HeightLabel.Text = 'Height:';
            app.CGHeightEditField = uieditfield(app.CGPositionPanel, 'numeric');
            app.CGHeightEditField.Tooltip = {'Vertical distance from ground to CG, when car is loaded and stationary'};
            app.CGHeightUnitsDropDown = uidropdown(app.CGPositionPanel);
            app.CGHeightUnitsDropDown.Items = {'m', 'mm', 'in'};
            app.CGHeightUnitsDropDown.Value = 'mm';
            app.CGLengthUnitsDropDown = uidropdown(app.CGPositionPanel);
            app.CGLengthUnitsDropDown.Items = {'m', 'mm', 'in'};
            app.CGLengthUnitsDropDown.Value = 'mm';
            app.LengthEditFieldLabel = uilabel(app.CGPositionPanel);
            app.LengthEditFieldLabel.Text = 'Length:';
            app.CGLengthEditField = uieditfield(app.CGPositionPanel, 'numeric');
            app.WheelbaseLabel = uilabel(app.GeneralPanel);
            app.WheelbaseLabel.Text = 'Wheelbase:';
            app.WheelbaseEditField = uieditfield(app.GeneralPanel, 'numeric');
            app.WheelbaseUnitsDropDown = uidropdown(app.GeneralPanel);
            app.WheelbaseUnitsDropDown.Items = {'m', 'mm', 'ft', 'in'};
            app.WheelbaseUnitsDropDown.Value = 'm';
            app.YawinertiaLabel = uilabel(app.GeneralPanel);
            app.YawinertiaLabel.Text = 'Yaw inertia:';
            app.YawInertiaEditField = uieditfield(app.GeneralPanel, 'numeric');
            app.YawInertiaUnitsDropDown = uidropdown(app.GeneralPanel);
            app.YawInertiaUnitsDropDown.Items = {'kg*m^2', 'lbm*inft^2'};
            app.YawInertiaUnitsDropDown.Value = 'kg*m^2';
            app.TrackWidthPanel = uipanel(app.GeneralPanel);
            app.TrackWidthPanel.Title = 'Track Width';
            app.FrontTrackWidthUnitsDropDown = uidropdown(app.TrackWidthPanel);
            app.FrontTrackWidthUnitsDropDown.Items = {'m', 'mm', 'ft', 'in'};
            app.FrontTrackWidthUnitsDropDown.Value = 'm';
            app.FrontEditField_5Label = uilabel(app.TrackWidthPanel);
            app.FrontEditField_5Label.Text = 'Front:';
            app.FrontTrackWidthEditField = uieditfield(app.TrackWidthPanel, 'numeric');
            app.RearEditField_5Label = uilabel(app.TrackWidthPanel);
            app.RearEditField_5Label.Text = 'Rear:';
            app.RearTrackWidthEditField = uieditfield(app.TrackWidthPanel, 'numeric');
            app.RearTrackWidthUnitsDropDown = uidropdown(app.TrackWidthPanel);
            app.RearTrackWidthUnitsDropDown.Items = {'m', 'mm', 'ft', 'in'};
            app.RearTrackWidthUnitsDropDown.Value = 'm';
            app.CarmassEditFieldLabel = uilabel(app.GeneralPanel);
            app.CarmassEditFieldLabel.Text = 'Car mass:';
            app.CarMassEditField = uieditfield(app.GeneralPanel, 'numeric');
            app.AerodynamicsPanel = uipanel(app.PropertiesPanel);
            app.AerodynamicsPanel.Title = 'Aerodynamics';
            app.AeromassLabel = uilabel(app.AerodynamicsPanel);
            app.AeromassLabel.Text = 'Aero mass:';
            app.AeroMassEditField = uieditfield(app.AerodynamicsPanel, 'numeric');
            app.AeroMassUnitsDropDown = uidropdown(app.AerodynamicsPanel);
            app.AeroMassUnitsDropDown.Items = {'kg', 'lbm'};
            app.AeroMassUnitsDropDown.Value = 'kg';
            app.DragcoefficientEditFieldLabel = uilabel(app.AerodynamicsPanel);
            app.DragcoefficientEditFieldLabel.Text = 'Drag coefficient:';
            app.DragCoefficientEditField = uieditfield(app.AerodynamicsPanel, 'numeric');
            app.LiftcoefficientEditFieldLabel = uilabel(app.AerodynamicsPanel);
            app.LiftcoefficientEditFieldLabel.Text = 'Lift coefficient:';
            app.LiftCoefficientEditField = uieditfield(app.AerodynamicsPanel, 'numeric');
            app.FrontalareaLabel = uilabel(app.AerodynamicsPanel);
            app.FrontalareaLabel.Text = 'Frontal area:';
            app.FrontalAreaEditField = uieditfield(app.AerodynamicsPanel, 'numeric');
            app.FrontalAreaUnitsDropDown = uidropdown(app.AerodynamicsPanel);
            app.FrontalAreaUnitsDropDown.Items = {'m^2', 'ft^2', 'in^2'};
            app.FrontalAreaUnitsDropDown.Value = 'm^2';
            app.SaveButton = uibutton(app.CarPropertiesPanel, 'push');
            app.SaveButton.Text = 'Save';
            app.CancelButton = uibutton(app.CarPropertiesPanel, 'push');
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.RFRVehicleModelUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = vehicle_model

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RFRVehicleModelUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RFRVehicleModelUIFigure)
        end
    end
end